classdef GlobalTemps < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BerkeleyEarthGlobalSurfaceTemperatureEstimateUIFigure  matlab.ui.Figure
        UIAxes                      matlab.ui.control.UIAxes
        DataSourceButtonGroup       matlab.ui.container.ButtonGroup
        MonthlyButton               matlab.ui.control.RadioButton
        MonthlyAverageButton        matlab.ui.control.RadioButton
        BothButton                  matlab.ui.control.RadioButton
        StartKnobLabel              matlab.ui.control.Label
        StartKnob                   matlab.ui.control.Knob
        StopKnobLabel               matlab.ui.control.Label
        StopKnob                    matlab.ui.control.Knob
        AveragingWindowSliderLabel  matlab.ui.control.Label
        AveragingWindowSlider       matlab.ui.control.Slider
        DefaultSettingsButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        Averages        % moving average of Temperatures
        AveragingWindow % width of average window (in months)
        ShowAverage;    % plot average
        ShowMonthly     % plot monthly
        StartYear       % year to start the plot
        StopYear        % year to stop the plot
        Temperatures    % global temperatures (in C)
        TimePoints      % measurement times (in years)
        
    end
    
    methods (Access = private)
        
        function plotData(app)
            xlim(app.UIAxes,[app.StartYear app.StopYear])
            xlim(app.UIAxes,'manual');
            ylim(app.UIAxes,[-1.5,1.5]);
            ylim(app.UIAxes,'manual');
            grid(app.UIAxes,'on');
            if app.ShowMonthly
                plot(app.UIAxes,app.TimePoints,app.Temperatures)
                hold(app.UIAxes,'on');
            end
            if app.ShowAverage
                % Calculate range to keep window inside TimePoints:
                half_window = app.AveragingWindow/2;
                start = floor(half_window) + 1;
                stop = length(app.TimePoints) - (ceil(half_window) - 1);
                inside_range = start:stop;
                PartialTimePoints = app.TimePoints(inside_range);
                PartialAverages = app.Averages(inside_range);
                % Plot only the data within the calculated range:
                plot(app.UIAxes,PartialTimePoints,PartialAverages,'r','LineWidth',2);
            end
            hold(app.UIAxes,'off');
        end
        
        function computeAverages(app)
            app.Averages = movmean(app.Temperatures,app.AveragingWindow); 
        end
        
        
        function setDefaults(app)
            app.AveragingWindowSlider.Value = app.AveragingWindowSlider.Limits(2)/2;
            app.BothButton.Value = true;
            app.MonthlyButton.Value = false;
            app.MonthlyAverageButton.Value = false;
            app.StartKnob.Value = app.StartKnob.Limits(1);
            app.StopKnob.Value = app.StopKnob.Limits(2);
            % Set properties:
            app.AveragingWindow = app.AveragingWindowSlider.Value;
            app.ShowAverage = app.MonthlyAverageButton.Value || app.BothButton.Value;
            app.ShowMonthly = app.MonthlyButton.Value || app.BothButton.Value;
            app.StartYear = app.StartKnob.Value;
            app.StopYear = app.StopKnob.Value;
            % Plot data:
            app.computeAverages();
            app.plotData();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load GlobalTemp.mat data
            app.TimePoints = data(:,1);
            app.Temperatures = data(:,2);
            app.setDefaults();
        end

        % Selection changed function: DataSourceButtonGroup
        function DataSourceButtonGroupSelectionChanged(app, event)
            app.ShowAverage = app.MonthlyAverageButton.Value || app.BothButton.Value;
            app.ShowMonthly = app.MonthlyButton.Value || app.BothButton.Value;
            app.plotData();
        end

        % Value changing function: StartKnob
        function StartKnobValueChanging(app, event)
            app.StartYear = event.Value;
            if app.StartYear > app.StopYear - 1 % checks the increasing-x rule
                app.StopYear = min([app.StartYear+1,app.StopKnob.Limits(2)]); 
                app.StopKnob.Value = app.StopYear; % rotates the stop knob
                app.StartYear = app.StopYear - 1;
                app.StartKnob.Value = app.StartYear;               
            end
            app.plotData();
            
        end

        % Value changing function: StopKnob
        function StopKnobValueChanging(app, event)
            app.StopYear = event.Value;
            if app.StopYear < app.StartYear + 1
                app.StartYear = max([app.StopYear-1 app.StartKnob.Limits(1)]); 
                app.StartKnob.Value = app.StartYear;
                app.StopYear = app.StartYear + 1; 
                app.StopKnob.Value = app.StopYear;
            end             
            app.plotData();

        end

        % Value changing function: AveragingWindowSlider
        function AveragingWindowSliderValueChanging(app, event)
            app.AveragingWindow = event.Value;
            app.computeAverages();
            app.plotData();           
            
        end

        % Button pushed function: DefaultSettingsButton
        function DefaultSettingsButtonPushed(app, event)
            app.setDefaults();            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BerkeleyEarthGlobalSurfaceTemperatureEstimateUIFigure = uifigure('Visible', 'off');
            app.BerkeleyEarthGlobalSurfaceTemperatureEstimateUIFigure.Name = 'Berkeley Earth Global Surface Temperature Estimate';
            app.UIAxes = uiaxes(app.BerkeleyEarthGlobalSurfaceTemperatureEstimateUIFigure);
            title(app.UIAxes, 'Global Temperature Anomaly')
            xlabel(app.UIAxes, 'Year')
            ylabel(app.UIAxes, 'C (relative to the 1950-1980 mean)')
            app.DataSourceButtonGroup = uibuttongroup(app.BerkeleyEarthGlobalSurfaceTemperatureEstimateUIFigure);
            app.DataSourceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DataSourceButtonGroupSelectionChanged, true);
            app.DataSourceButtonGroup.Title = 'Data Source';
            app.MonthlyButton = uiradiobutton(app.DataSourceButtonGroup);
            app.MonthlyButton.Text = 'Monthly';
            app.MonthlyAverageButton = uiradiobutton(app.DataSourceButtonGroup);
            app.MonthlyAverageButton.Text = 'Monthly Average';
            app.BothButton = uiradiobutton(app.DataSourceButtonGroup);
            app.BothButton.Text = 'Both';
            app.BothButton.Value = true;
            app.StartKnobLabel = uilabel(app.BerkeleyEarthGlobalSurfaceTemperatureEstimateUIFigure);
            app.StartKnobLabel.Text = 'Start';
            app.StartKnob = uiknob(app.BerkeleyEarthGlobalSurfaceTemperatureEstimateUIFigure, 'continuous');
            app.StartKnob.Limits = [1850 2020];
            app.StartKnob.MajorTicks = [1850 1910 1960 2020];
            app.StartKnob.ValueChangingFcn = createCallbackFcn(app, @StartKnobValueChanging, true);
            app.StartKnob.Value = 1850;
            app.StopKnobLabel = uilabel(app.BerkeleyEarthGlobalSurfaceTemperatureEstimateUIFigure);
            app.StopKnobLabel.Text = 'Stop';
            app.StopKnob = uiknob(app.BerkeleyEarthGlobalSurfaceTemperatureEstimateUIFigure, 'continuous');
            app.StopKnob.Limits = [1850 2020];
            app.StopKnob.MajorTicks = [1850 1910 1960 2020];
            app.StopKnob.ValueChangingFcn = createCallbackFcn(app, @StopKnobValueChanging, true);
            app.StopKnob.Value = 2020;
            app.AveragingWindowSliderLabel = uilabel(app.BerkeleyEarthGlobalSurfaceTemperatureEstimateUIFigure);
            app.AveragingWindowSliderLabel.Text = 'Averaging Window';
            app.AveragingWindowSlider = uislider(app.BerkeleyEarthGlobalSurfaceTemperatureEstimateUIFigure);
            app.AveragingWindowSlider.Limits = [6 120];
            app.AveragingWindowSlider.MajorTicks = [6 30 60 90 120];
            app.AveragingWindowSlider.ValueChangingFcn = createCallbackFcn(app, @AveragingWindowSliderValueChanging, true);
            app.AveragingWindowSlider.Value = 6;
            app.DefaultSettingsButton = uibutton(app.BerkeleyEarthGlobalSurfaceTemperatureEstimateUIFigure, 'push');
            app.DefaultSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultSettingsButtonPushed, true);
            app.DefaultSettingsButton.Text = 'Default Settings';
            % Show the figure after all components are created
            app.BerkeleyEarthGlobalSurfaceTemperatureEstimateUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GlobalTemps

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BerkeleyEarthGlobalSurfaceTemperatureEstimateUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BerkeleyEarthGlobalSurfaceTemperatureEstimateUIFigure)
        end
    end
end